classdef MontageSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavetoExcelMenu                 matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ConfigureCablingButton          matlab.ui.control.Button
        SwitchNSPsButton                matlab.ui.control.Button
        EmptyChannelHandlingPanel       matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        RecordEmptyChannelsCheckBox     matlab.ui.control.CheckBox
        SFDropDown                      matlab.ui.control.DropDown
        SFDropDownLabel                 matlab.ui.control.Label
        SRDropDown                      matlab.ui.control.DropDown
        SRDropDownLabel                 matlab.ui.control.Label
        AddEditArrayPanel               matlab.ui.container.Panel
        SetselectedasGNDButton          matlab.ui.control.Button
        SetselectedasCOMButton          matlab.ui.control.Button
        TBarDropDown                    matlab.ui.control.DropDown
        TBarDropDownLabel               matlab.ui.control.Label
        EIDEditField                    matlab.ui.control.NumericEditField
        EIDEditFieldLabel               matlab.ui.control.Label
        PinIDEditField                  matlab.ui.control.EditField
        PinIDEditFieldLabel             matlab.ui.control.Label
        SpikeFilterDropDown             matlab.ui.control.DropDown
        SpikeFilterDropDownLabel        matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        ResetSelectedFieldsButton       matlab.ui.control.Button
        SamplingFilterDropDown          matlab.ui.control.DropDown
        SamplingFilterDropDownLabel     matlab.ui.control.Label
        SamplingRateDropDown            matlab.ui.control.DropDown
        SamplingRateDropDownLabel       matlab.ui.control.Label
        WriteArrayToTableButton         matlab.ui.control.Button
        NumberofContactsEditField       matlab.ui.control.NumericEditField
        NumberofContactsEditFieldLabel  matlab.ui.control.Label
        ArrayIDEditField                matlab.ui.control.EditField
        ArrayIDEditFieldLabel           matlab.ui.control.Label
        ApplyTablestoCentralButton      matlab.ui.control.Button
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDLabel                  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NSP1Tab                         matlab.ui.container.Tab
        NSP1_Table                      matlab.ui.control.Table
        NSP2Tab                         matlab.ui.container.Tab
        NSP2_Table                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        Indices
        NihonKohdenTBarLabels = {'A','B','C','D'};
        ElectrodesPerTBar = 64;
        BlackrockNSPLabels = {'NSP1','NSP2'}
        ElectrodesPerNSP = 256;
        activeEmptyChannels = cell(2,1);
        NSPTable = cell(2,1);
        TableHeaders = {'TBarPinID','ElectrodeID','ChannelLabel','Notes','SamplingRate','SamplingFilter','SpikeFilter'};
        SamplingRateCoding = {'None',0;'500Hz',1;'1kHz',2;'2kHz',3;'10kHz',4;'30kHz',5};
        FilterCoding = {'None',0;'750Hz Highpass',1;'250Hz Highpass',2;'100Hz Highpass',3;'50Hz Lowpass',4;'125Hz Lowpass',5;...
            '250Hz Lowpass',6;'500Hz Lowpass',7;'150Hz Lowpass',8;'10Hz-250Hz Bandpass',9;'2.5kHz Lowpass',10;...
            '2kHz Lowpass',11;'250Hz-5kHz Bandpass',12};

        NICPortNames = {'NSP1','NSP2'};
        NSPAddress
        EmptyChannelHandling
    end

    properties (Access = private)
        CableConfigurationApp
    end

    methods (Access = public)

        function updateCurrentTable(app)
            inc = app.EIDEditField.Value;
            noc = app.NumberofContactsEditField.Value;
            al = app.ArrayIDEditField.Value;
            if ~isempty(regexp(al,'\d$','once'))
                al = [al '-'];
            end
            if ceil(log10(abs(noc)+1))==3
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%03d',1:noc),[3,noc])'];
            else
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%02d',1:noc),[2,noc])'];
            end
            contactLabels = cellstr(contactLabels);
            sr = cellstr(repmat(app.SamplingRateDropDown.Value,[noc,1]));
            sf = cellstr(repmat(app.SamplingFilterDropDown.Value,[noc,1]));
            spkf = cellstr(repmat(app.SpikeFilterDropDown.Value,[noc,1]));
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end
            ind = find(app.NSPTable{T}.ElectrodeID==inc);
            app.NSPTable{T}.ChannelLabel(ind:ind+noc-1) = contactLabels;
            app.NSPTable{T}.SamplingRate(ind:ind+noc-1) = sr;
            app.NSPTable{T}.SamplingFilter(ind:ind+noc-1) = sf;
            if strcmp(app.SpikeFilterDropDown.Enable,'on') == 1
                app.NSPTable{T}.SpikeFilter(ind:ind+noc-1) = spkf;
            end
            app.EIDEditField.Value = app.NSPTable{T}.ElectrodeID(mod(ind+noc,app.ElectrodesPerNSP+1));
            EIDEditFieldValueChanged(app);
        end

        function tableCellEdit(app,indices,tab)
            firstRow = indices(1,1);
            firstColumn = indices(1,2);
            newData = event.NewData;
            if firstColumn==3
                if ~contains(newData,'empty')
                    if ~isempty(regexp(newData,'[a-zA-Z]$','once'))
                        newData = sprintf('%s%02d',newData,1);
                    elseif ~isempty(regexp(newData,'[a-zA-Z]\d{1}$','once'))
                        tmp = regexp(newData,'\w*(\d{1})$','tokens');
                        tmp = str2double(cell2mat(vertcat(tmp{:})));
                        newData = sprintf('%s%02d',newData(1:end-1),tmp);
                    end
                end
            end
            app.NSPTable{tab}(firstRow,firstColumn) = cellstr(newData);
        end

        function tableCellSelection(app,indices,tab)
            firstRow = indices(1,1);
            lastRow = indices(end,1);
            firstColumn = indices(1,2);
            app.Indices = indices;
            app.EIDEditField.Value = app.NSPTable{tab}.ElectrodeID(firstRow);
            app.PinIDEditField.Value = app.NSPTable{tab}.TBarPinID{firstRow};
            app.TBarDropDown.Value = regexp(app.NSPTable{tab}.TBarPinID{firstRow},'[A-Z]','match');
            if firstColumn==3
                nRows = length(firstRow:lastRow);
                sr = cellstr(repmat(app.NSPTable{tab}.SamplingRate(firstRow),[nRows,1]));
                sf = cellstr(repmat(app.NSPTable{tab}.SamplingFilter(firstRow),[nRows,1]));
                spksf = cellstr(repmat(app.NSPTable{tab}.SpikeFilter(firstRow),[nRows,1]));
                if ~contains(app.NSPTable{tab}.ChannelLabel(firstRow),'empty')
                    label = cell2mat(app.NSPTable{tab}.ChannelLabel(firstRow));
                    tmp = regexp(label,'([\w-]+?)(\d{2,3})$','tokens');
                    tmp = vertcat(tmp{:});
                    [arrayID,num] = tmp{:};
                    num = str2double(num);
                    if ceil(log10(abs(nRows)+1))==3
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%03d',num:num+nRows-1),[3,nRows])'];
                    else
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%02d',num:num+nRows-1),[2,nRows])'];
                    end
                    contactLabels = cellstr(contactLabels);
                    app.NSPTable{tab}.ChannelLabel(firstRow:lastRow) = contactLabels;
                    app.NSPTable{tab}.SamplingRate(firstRow:lastRow) = sr;
                    app.NSPTable{tab}.SamplingFilter(firstRow:lastRow) = sf;
                    app.NSPTable{tab}.SpikeFilter(firstRow:lastRow) = spksf;
                end
                emptyChannelHandling(app)
                displayUpdatedTable(app)
            end
        end

        function emptyChannelHandling(app)
            for i = 1:numel(app.BlackrockNSPLabels)
                indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.ChannelLabel,'^empty')));
                sr = cell(length(indexNSP),1);
                sf = cell(length(indexNSP),1);
                sr(:) = app.SamplingRateCoding(1,1);
                sf(:) = app.FilterCoding(1,1);
                spkf = cellstr(blanks(length(indexNSP))');
                app.NSPTable{i}.SamplingRate(indexNSP) = sr;
                app.NSPTable{i}.SamplingFilter(indexNSP) = sf;
                app.NSPTable{i}.SpikeFilter(indexNSP) = spkf;
            end

            if app.RecordEmptyChannelsCheckBox.Value == 1
                app.DropDown.Enable = 1;
                app.SRDropDown.Enable = 1;
                app.SFDropDown.Enable = 1;

                switch app.DropDown.Value
                    case 'All'
                        for i = 1:numel(app.BlackrockNSPLabels)
                            indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.ChannelLabel,'^empty')));
                            app.activeEmptyChannels{i} = indexNSP;
                        end

                    case 'One'
                        for i = 1:numel(app.BlackrockNSPLabels)
                            indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.ChannelLabel,'^empty')),1,'last');
                            app.activeEmptyChannels{i} = indexNSP;
                        end

                    case 'One per TBar'
                        for i = 1:numel(app.BlackrockNSPLabels)
                            indAmp = arrayfun(@(x) find(contains(app.NSPTable{i}.TBarPinID,x)),'A':'D','Uni',0);
                            indEmpty = find(~cellfun(@isempty,regexp(app.NSPTable{i}.ChannelLabel,'^empty')));
                            tmp = cellfun(@(x) intersect(x,indEmpty),indAmp,'Uni',0);
                            tmp = tmp(~cellfun(@isempty,tmp));
                            indexNSP = cell2mat(cellfun(@(x) x(end),tmp,'Uni',0));
                            app.activeEmptyChannels{i} = indexNSP;
                        end
                end

                for i = 1:numel(app.BlackrockNSPLabels)
                    if ~isempty(app.activeEmptyChannels{i})
                        sr = cellstr(repmat(app.SRDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        sf = cellstr(repmat(app.SFDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        app.NSPTable{i}.SamplingRate(app.activeEmptyChannels{i}) = sr;
                        app.NSPTable{i}.SamplingFilter(app.activeEmptyChannels{i}) = sf;
                    end
                end

            else
                app.DropDown.Enable = 0;
                app.SRDropDown.Enable = 0;
                app.SFDropDown.Enable = 0;

                for i = 1:numel(app.BlackrockNSPLabels)
                    app.activeEmptyChannels{i} = [];
                end
            end
        end

        function displayUpdatedTable(app)
            for i = 1:numel(app.BlackrockNSPLabels)
                app.([app.BlackrockNSPLabels{i},'_Table']).Data = app.NSPTable{i};
            end
        end

        function reorderElectrodeID(app,reorderingMatrix)
            for i = 1:size(reorderingMatrix,2)
                app.NSPTable{i}.ElectrodeID = app.NSPTable{i}.ElectrodeID(reorderingMatrix(:,i));
            end
            emptyChannelHandling(app)
            displayUpdatedTable(app)
            EIDEditFieldValueChanged(app);
        end

        function writeTablesToCentral(app,table,instance)
            tmpChannelNames = table.ChannelLabel;
            dash = cellstr(repmat('-',[256,1]));
            tmpElectrodeIDs = cellstr(num2str(table.ElectrodeID,'%03d'));
            ChannelLabels = cellfun(@(x,y,z) [x,y,z],tmpChannelNames,dash,tmpElectrodeIDs,'Uni',0);

            ChannelSamplingRate = table.SamplingRate;
            ChannelSamplingFilter = table.SamplingFilter;
            ChannelSpikeFilter = table.SpikeFilter;

            sr = zeros(length(ChannelSamplingRate),1);
            sf = zeros(length(ChannelSamplingFilter),1);
            spksf = nan(length(ChannelSpikeFilter),1);
            for i=1:length(sr)
                sr(i) = app.SamplingRateCoding{strcmp(app.SamplingRateCoding(:,1),ChannelSamplingRate{i}),2};
                sf(i) = app.FilterCoding{strcmp(app.FilterCoding(:,1),ChannelSamplingFilter{i}),2};
                if isempty(ChannelSpikeFilter{i})
                    continue
                else
                    spksf(i) = app.FilterCoding{strcmp(app.FilterCoding(:,1),ChannelSpikeFilter{i}),2};
                end
            end

            try
                cbmex('open','central-addr',app.NSPAddress{instance},'instance',instance-1)
            catch ME
                if instance==1
                    uialert(app.UIFigure,'Please Open an Instance of Central and Try Again','Cental Not Open')
                    disp(ME.message)
%                 else
%                     uialert(app.UIFigure,'To upload the channel data for NSP2, please open the second instance of Central and try again','Second Cental Instance Not Open')
                end
                return
            end

            for i = 1:256
                if isnan(spksf(i))
                    cbmex('config',table.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'instance',instance-1)
                else
                    cbmex('config',table.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkfilter',spksf(i),'instance',instance-1)
                    %                     cbmex('config',table.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkextract',1,'spkfilter',spksf(i),'instance',instance-1)
                end
            end
            cbmex('chanlabel',table.ElectrodeID,ChannelLabels,'instance',instance-1)
            pause(2)
            cbmex('close')
        end

        function updateEmptyChannelHandlingFields(app,instance)
            app.RecordEmptyChannelsCheckBox.Value = app.EmptyChannelHandling(instance).RecordEmptyValue;
            app.DropDown.Value = app.EmptyChannelHandling(instance).RecordVariationValue;
            app.SFDropDown.Value = app.EmptyChannelHandling(instance).SFValue;
            app.SRDropDown.Value = app.EmptyChannelHandling(instance).SRValue;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')

            tmp = arrayfun(@(x) sprintf('%02d',x),1:app.ElectrodesPerTBar,'Uni',0);
            TBarPinID = {};
            for i = 1:numel(app.NihonKohdenTBarLabels)
                TBarPinID = [TBarPinID cellfun(@(x) [app.NihonKohdenTBarLabels{i} x],tmp,'Uni',0)];
            end
            TBarPinID = TBarPinID';

            ElectrodeID = (1:app.ElectrodesPerNSP)';
            ChannelLabel = cellstr(repmat('empty',[length(ElectrodeID),1]));
            Notes = cellstr(blanks(length(ElectrodeID))');
            SamplingRate = cellstr(blanks(length(ElectrodeID))');
            SamplingFilter = cellstr(blanks(length(ElectrodeID))');
            SpkFilter = cellstr(blanks(length(ElectrodeID))');
            s = uistyle('HorizontalAlignment','center');
            for i = 1:numel(app.BlackrockNSPLabels)
                app.NSPTable{i} = table(TBarPinID,ElectrodeID,ChannelLabel,Notes,SamplingRate,SamplingFilter,SpkFilter,'VariableNames',app.TableHeaders);
                app.([app.BlackrockNSPLabels{i},'_Table']).ColumnName = app.TableHeaders;
                addStyle(app.([app.BlackrockNSPLabels{i},'_Table']),s,'column',[1 2])
            end

            app.NSPAddress = getIPAddressesFromPortNames(app.NICPortNames);
            
            app.EmptyChannelHandling = struct;
            app.EmptyChannelHandling(1).RecordEmptyValue = 1;
            app.EmptyChannelHandling(1).RecordVariationValue = 3;
            app.EmptyChannelHandling(1).SRValue = 4;
            app.EmptyChannelHandling(1).SFValue = 8;
            app.EmptyChannelHandling(2).RecordEmptyValue = 0;
            app.EmptyChannelHandling(2).RecordVariationValue = 2;
            app.EmptyChannelHandling(2).SRValue = 6;
            app.EmptyChannelHandling(2).SFValue = 1;

            emptyChannelHandling(app)
            displayUpdatedTable(app)
            app.UIFigure.Visible = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {950, 950};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SavetoExcelMenu
        function SavetoExcelButtonPushed(app, event)
            patientPath = fullfile(userpath,'PatientData',app.PatientIDEditField.Value);
            if ~exist(patientPath,'dir')
                answer = questdlg(['The folder for ', app.PatientIDEditField.Value,' does not exist. Would you like to create it?'],'Create Patient Folder','Yes','No','Yes');
                if strcmp(answer,'Yes')
                    mkdir(patientPath)
                end
            end
            [file,path] = uiputfile(fullfile(patientPath,[app.PatientIDEditField.Value,'_montage.xlsx']));
            figure(app.UIFigure)
            if file==0
                return
            end

            for i = 1:2
                writetable(app.NSPTable{i},fullfile(path,file),'Sheet',i)
            end
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            [file,dir]=uigetfile({'*.xlsx','Excel Files(*.xlsx'},'Select File');
            figure(app.UIFigure)
            if file==0
                return
            end
            [~,filename]=fileparts(fullfile(dir,file));
            app.PatientIDEditField.Value = erase(filename,'_montage');
            app.SavetoExcelMenu.Enable = 1;

            for i = 1:2
                app.NSPTable{i} = readtable(fullfile(dir,file),'Sheet',i);
                for j = 4:7
                    if ~iscell(app.NSPTable{i}.(app.TableHeaders{j}))
                        app.NSPTable{i}.(app.TableHeaders{j}) = cellstr(blanks(size(app.NSPTable{i},1))');
                    end
                end
            end

            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: ApplyTablestoCentralButton
        function ApplyTablestoCentralButtonPushed(app, event)
            if ismac
                uialert(app.UIFigure,'Button does not work for Mac OS.','Mac OS Incompatibility')
                return
            end

            %             switch app.TabGroup.SelectedTab.Title
            %                 case 'NSP1'
            %                     table = app.NSPTable{1};
            %                     tab = 1;
            %                 case 'NSP2'
            %                     table = app.NSPTable{2};
            %                     tab = 2;
            %             end

            for i = 1:2
                writeTablesToCentral(app,app.NSPTable{i},i)
            end
        end

        % Button pushed function: SwitchNSPsButton
        function SwitchNSPsButtonPushed(app, event)
            app.NSPTable = app.NSPTable([2,1]);
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: RecordEmptyChannelsCheckBox
        function RecordEmptyChannelsCheckBoxValueChanged(app, event)
            instance = str2double(app.TabGroup.SelectedTab.Title(end));
            app.EmptyChannelHandling(instance).RecordEmptyValue = app.RecordEmptyChannelsCheckBox.Value;
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            instance = str2double(app.TabGroup.SelectedTab.Title(end));
            app.EmptyChannelHandling(instance).RecordVariationValue = app.DropDown.Value;
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: SRDropDown
        function SRDropDownValueChanged(app, event)
            instance = str2double(app.TabGroup.SelectedTab.Title(end));
            app.EmptyChannelHandling(instance).SRValue = app.SRDropDown.Value;
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: SFDropDown
        function SFDropDownValueChanged(app, event)
            instance = str2double(app.TabGroup.SelectedTab.Title(end));
            app.EmptyChannelHandling(instance).SFValue = app.SFDropDown.Value;
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: TBarDropDown
        function TBarDropDownValueChanged(app, event)
            ind = find(contains(app.NSPTable{1}.TBarPinID,app.TBarDropDown.Value),1);
            app.PinIDEditField.Value = app.NSPTable{1}.TBarPinID{ind};
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    app.EIDEditField.Value = app.NSPTable{1}.ElectrodeID(ind);
                case 'NSP2'
                    app.EIDEditField.Value = app.NSPTable{2}.ElectrodeID(ind);
            end
        end

        % Value changed function: EIDEditField
        function EIDEditFieldValueChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    ind = find(app.NSPTable{1}.ElectrodeID==app.EIDEditField.Value);
                case 'NSP2'
                    ind = find(app.NSPTable{2}.ElectrodeID==app.EIDEditField.Value);
            end
            app.PinIDEditField.Value = app.NSPTable{1}.TBarPinID{ind};
            app.TBarDropDown.Value = regexp(app.NSPTable{1}.TBarPinID{ind},'[A-Z]','match');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.CableConfigurationApp)
            delete(app)
        end

        % Button pushed function: WriteArrayToTableButton
        function WriteArrayToTableButtonPushed(app, event)
            updateCurrentTable(app)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: ResetSelectedFieldsButton
        function ResetSelectedFieldsButtonPushed(app, event)
            rows = unique(app.Indices(:,1));
            ChannelLabel = cellstr(repmat('empty',[length(rows),1]));
            Notes = cellstr(blanks(length(rows))');

            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end

            app.NSPTable{T}.ChannelLabel(rows) = ChannelLabel;
            app.NSPTable{T}.Notes(rows) = Notes;

            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell edit callback: NSP1_Table
        function NSP1_TableCellEdit(app, event)
            indices = event.Indices;
            tab = 1;
            tableCellEdit(app,indices,tab)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell selection callback: NSP1_Table
        function NSP1_TableCellSelection(app, event)
            indices = event.Indices;
            if indices(1,1)==1 && indices(size(indices,1),1)==256
                if ismember(indices(1,2),[1,2])==1
                    app.NSPTable{1} = sortrows(app.NSPTable{1},app.TableHeaders{indices(1,2)});
                    emptyChannelHandling(app)
                    displayUpdatedTable(app)
                else
                    return
                end
            end
            tab = 1;
            tableCellSelection(app,indices,tab)
        end

        % Cell edit callback: NSP2_Table
        function NSP2_TableCellEdit(app, event)
            indices = event.Indices;
            tab = 2;
            tableCellEdit(app,indices,tab)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell selection callback: NSP2_Table
        function NSP2_TableCellSelection(app, event)
            indices = event.Indices;
            if indices(1,1)==1 && indices(size(indices,1),1)==256
                if ismember(indices(1,2),[1,2])==1
                    app.NSPTable{2} = sortrows(app.NSPTable{2},app.TableHeaders{indices(1,2)});
                    emptyChannelHandling(app)
                    displayUpdatedTable(app)
                else
                    return
                end
            end
            tab = 2;
            tableCellSelection(app,indices,tab)
        end

        % Button pushed function: ConfigureCablingButton
        function ConfigureCablingButtonPushed(app, event)
            app.ConfigureCablingButton.Enable = 'off';
            ElectrodeID = (1:app.ElectrodesPerNSP)';
            for i = 1:2
                app.NSPTable{i}.ElectrodeID = ElectrodeID;
            end
            app.CableConfigurationApp = MontageSetupWizard(app);
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            if ~isempty(app.PatientIDEditField.Value)
                app.SavetoExcelMenu.Enable = 1;
            else
                app.SavetoExcelMenu.Enable = 0;
            end

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'m')
                app.SamplingRateDropDown.Value = '30kHz';
                app.SamplingFilterDropDown.Value = 'None';
                app.SpikeFilterDropDown.Value = '250Hz Highpass';
                app.SpikeFilterDropDown.Enable = 1;
            else
                app.SamplingRateDropDown.Value = '2kHz';
                app.SamplingFilterDropDown.Value = '500Hz Lowpass';
                app.SpikeFilterDropDown.Enable = 0;
            end

        end

        % Button pushed function: SetselectedasCOMButton
        function SetselectedasCOMButtonPushed(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end

            Labels = app.NSPTable{T}.ChannelLabel;

            %Remove Previous COM Label if exists
            Labels = regexprep(Labels,'^C','');

            %Overwrite GND Label if on Selected Label or Append COM Label
            if isempty(regexp(Labels{app.Indices(1)},'^Z','once'))
                Labels{app.Indices(1)} = ['C' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^Z','C');
            end

            app.NSPTable{T}.ChannelLabel = Labels;

            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: SetselectedasGNDButton
        function SetselectedasGNDButtonPushed(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end

            Labels = app.NSPTable{T}.ChannelLabel;

            %Remove Previous GND Label if exists
            Labels = regexprep(Labels,'^Z','');

            %Overwrite COM Label if on Selected Label or Append GND Label
            if isempty(regexp(Labels{app.Indices(1)},'^C','once'))
                Labels{app.Indices(1)} = ['Z' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^C','Z');
            end

            app.NSPTable{T}.ChannelLabel = Labels;

            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changing function: ArrayIDEditField
        function ArrayIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.TypeDropDown.Value)
                if length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.WriteArrayToTableButton.Enable = 1;
                elseif length(changingValue)<10
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.WriteArrayToTableButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.WriteArrayToTableButton.Enable = 0;
                end
            else
                if length(changingValue)<8
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.WriteArrayToTableButton.Enable = 1;
                elseif length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.WriteArrayToTableButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.WriteArrayToTableButton.Enable = 0;
                end
            end
        end

        % Value changed function: NumberofContactsEditField
        function NumberofContactsEditFieldValueChanged(app, event)
            ArrayIDEditFieldValueChanging(app,event)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            instance = str2double(app.TabGroup.SelectedTab.Title(end));
            if instance == 1
                app.TypeDropDown.Value = '';
            elseif instance == 2
                app.TypeDropDown.Value = 'm';
            end
            TypeDropDownValueChanged(app)
            updateEmptyChannelHandlingFields(app,instance)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Montage Setup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavetoExcelMenu = uimenu(app.FileMenu);
            app.SavetoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoExcelButtonPushed, true);
            app.SavetoExcelMenu.Enable = 'off';
            app.SavetoExcelMenu.Text = 'Save to Excel';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PatientIDLabel = uilabel(app.LeftPanel);
            app.PatientIDLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.LeftPanel, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.ApplyTablestoCentralButton = uibutton(app.LeftPanel, 'push');
            app.ApplyTablestoCentralButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTablestoCentralButtonPushed, true);
            app.ApplyTablestoCentralButton.Text = 'Apply Tables to Central';
            app.AddEditArrayPanel = uipanel(app.LeftPanel);
            app.AddEditArrayPanel.Title = 'Add/Edit Array';
            app.ArrayIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.ArrayIDEditFieldLabel.Text = 'Array ID';
            app.ArrayIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.ArrayIDEditField.ValueChangingFcn = createCallbackFcn(app, @ArrayIDEditFieldValueChanging, true);
            app.NumberofContactsEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.NumberofContactsEditFieldLabel.Text = 'Number of Contacts';
            app.NumberofContactsEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.NumberofContactsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofContactsEditFieldValueChanged, true);
            app.NumberofContactsEditField.Value = 1;
            app.WriteArrayToTableButton = uibutton(app.AddEditArrayPanel, 'push');
            app.WriteArrayToTableButton.ButtonPushedFcn = createCallbackFcn(app, @WriteArrayToTableButtonPushed, true);
            app.WriteArrayToTableButton.Text = 'Write Array To Table';
            app.SamplingRateDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingRateDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SamplingRateDropDown.Value = '2kHz';
            app.SamplingFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingFilterDropDownLabel.Text = 'Sampling Filter';
            app.SamplingFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SamplingFilterDropDown.Value = '500Hz Lowpass';
            app.ResetSelectedFieldsButton = uibutton(app.AddEditArrayPanel, 'push');
            app.ResetSelectedFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSelectedFieldsButtonPushed, true);
            app.ResetSelectedFieldsButton.Text = 'Reset Selected Fields';
            app.TypeDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddEditArrayPanel);
            app.TypeDropDown.Items = {'sEEG', 'Micro', 'Subdural', 'DBS', 'External'};
            app.TypeDropDown.ItemsData = {'', 'm', 'g', 'd', 'x'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '';
            app.SpikeFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SpikeFilterDropDownLabel.Text = 'Spike Filter';
            app.SpikeFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SpikeFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SpikeFilterDropDown.Enable = 'off';
            app.SpikeFilterDropDown.Value = '250Hz Highpass';
            app.PinIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.PinIDEditFieldLabel.Text = 'Pin ID';
            app.PinIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.PinIDEditField.Editable = 'off';
            app.PinIDEditField.Value = 'A01';
            app.EIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.EIDEditFieldLabel.Text = 'EID';
            app.EIDEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.EIDEditField.Limits = [1 256];
            app.EIDEditField.ValueChangedFcn = createCallbackFcn(app, @EIDEditFieldValueChanged, true);
            app.EIDEditField.Value = 1;
            app.TBarDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TBarDropDownLabel.Text = 'T-Bar';
            app.TBarDropDown = uidropdown(app.AddEditArrayPanel);
            app.TBarDropDown.Items = {'A', 'B', 'C', 'D'};
            app.TBarDropDown.ValueChangedFcn = createCallbackFcn(app, @TBarDropDownValueChanged, true);
            app.TBarDropDown.Value = 'A';
            app.SetselectedasCOMButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetselectedasCOMButton.ButtonPushedFcn = createCallbackFcn(app, @SetselectedasCOMButtonPushed, true);
            app.SetselectedasCOMButton.Text = 'Set selected as COM';
            app.SetselectedasGNDButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetselectedasGNDButton.ButtonPushedFcn = createCallbackFcn(app, @SetselectedasGNDButtonPushed, true);
            app.SetselectedasGNDButton.Text = 'Set selected as GND';
            app.EmptyChannelHandlingPanel = uipanel(app.LeftPanel);
            app.EmptyChannelHandlingPanel.Title = 'Empty Channel Handling';
            app.SRDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SRDropDownLabel.Enable = 'off';
            app.SRDropDownLabel.Text = 'SR';
            app.SRDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SRDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SRDropDown.ItemsData = [1 2 3 4 5 6];
            app.SRDropDown.ValueChangedFcn = createCallbackFcn(app, @SRDropDownValueChanged, true);
            app.SRDropDown.Enable = 'off';
            app.SRDropDown.Value = 2;
            app.SFDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SFDropDownLabel.Enable = 'off';
            app.SFDropDownLabel.Text = 'SF';
            app.SFDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SFDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SFDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.SFDropDown.ValueChangedFcn = createCallbackFcn(app, @SFDropDownValueChanged, true);
            app.SFDropDown.Enable = 'off';
            app.SFDropDown.Value = 8;
            app.RecordEmptyChannelsCheckBox = uicheckbox(app.EmptyChannelHandlingPanel);
            app.RecordEmptyChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordEmptyChannelsCheckBoxValueChanged, true);
            app.RecordEmptyChannelsCheckBox.Text = 'Record Empty Channels';
            app.RecordEmptyChannelsCheckBox.Value = true;
            app.DropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.DropDown.Items = {'All', 'One', 'One per TBar', ''};
            app.DropDown.ItemsData = [1 2 3];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 3;
            app.SwitchNSPsButton = uibutton(app.LeftPanel, 'push');
            app.SwitchNSPsButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchNSPsButtonPushed, true);
            app.SwitchNSPsButton.Text = 'Switch NSPs';
            app.ConfigureCablingButton = uibutton(app.LeftPanel, 'push');
            app.ConfigureCablingButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureCablingButtonPushed, true);
            app.ConfigureCablingButton.Text = 'Configure Cabling';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NSP1Tab = uitab(app.TabGroup);
            app.NSP1Tab.Title = 'NSP1';
            app.NSP1_Table = uitable(app.NSP1Tab);
            app.NSP1_Table.ColumnName = {''};
            app.NSP1_Table.RowName = {};
            app.NSP1_Table.CellEditCallback = createCallbackFcn(app, @NSP1_TableCellEdit, true);
            app.NSP1_Table.CellSelectionCallback = createCallbackFcn(app, @NSP1_TableCellSelection, true);
            app.NSP2Tab = uitab(app.TabGroup);
            app.NSP2Tab.Title = 'NSP2';
            app.NSP2_Table = uitable(app.NSP2Tab);
            app.NSP2_Table.ColumnName = {''};
            app.NSP2_Table.RowName = {};
            app.NSP2_Table.CellEditCallback = createCallbackFcn(app, @NSP2_TableCellEdit, true);
            app.NSP2_Table.CellSelectionCallback = createCallbackFcn(app, @NSP2_TableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MontageSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end