classdef MontageSetupWizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MontageSetupWizardUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        NKTBarsPanel                matlab.ui.container.Panel
        FullEditFieldLabel          matlab.ui.control.Label
        FullEditField               matlab.ui.control.NumericEditField
        HalfEditFieldLabel          matlab.ui.control.Label
        HalfEditField               matlab.ui.control.NumericEditField
        ConnectionStrategyDropDownLabel  matlab.ui.control.Label
        ConnectionStrategyDropDown  matlab.ui.control.DropDown
        MicrowireHeadstagesPanel    matlab.ui.container.Panel
        HeadstageEditField          matlab.ui.control.NumericEditField
        RecordedDBSPanel            matlab.ui.container.Panel
        DBSEditField                matlab.ui.control.NumericEditField
        GenerateLayoutButton        matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        CustomButton                matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        AmpDipSwitch
        NumberOfNSPs
        NumberOfAmps
        NumberOfTBars
        NumberOfSameRefEdges
        NumberOfEdges
        
        ElectrodesPerNSP = 256;
        ElectrodesPerBank = 32;
        BanksPerNSP = 8;
        BanksPerAmp = 4;
        
        Graph
        MarkerSize = 8;
        SetupApp
        ReorderingMatrix
        Test
    end
    
    methods (Access = private)
        
        
        function CalculateProperties(app)
            app.NumberOfEdges = 2*app.FullEditField.Value + app.HalfEditField.Value + app.DBSEditField.Value + app.HeadstageEditField.Value;
            app.NumberOfTBars = app.FullEditField.Value + app.HalfEditField.Value;
            app.NumberOfSameRefEdges = 2*app.FullEditField.Value + app.HalfEditField.Value+app.DBSEditField.Value;
            app.NumberOfAmps = ceil(app.NumberOfEdges/4);
            if mod(app.NumberOfAmps,2)==1 && strcmp(app.ConnectionStrategyDropDown.Value,'Split') % If an odd number of Amps required, but setup uses 'Split', add an extra amplifier to make even.
                app.NumberOfAmps = app.NumberOfAmps + 1;
            elseif strcmp(app.ConnectionStrategyDropDown.Value,'Epilepsy (Hybrid)') % Guarantees two amps minimum when using 'Epilepsy (Hybrid)' connection
                app.NumberOfAmps = max(app.NumberOfAmps,2);
            end
            app.NumberOfNSPs = ceil(app.NumberOfAmps/2);
        end
        
        
        function GenerateGraph(app)
            NodeLabels = struct();
            Degree = struct();
            TBarLabels = arrayfun(@(x) ['TBar-',x],char(65:65+app.NumberOfTBars-1)','Uni',0);
            DBSLabels = arrayfun(@(x) ['DBS-',int2str(x)],1:app.DBSEditField.Value,'Uni',0);
            MicroLabels = arrayfun(@(x) ['Cabrio-',int2str(x)],1:app.HeadstageEditField.Value,'Uni',0);
            NodeLabels.Level1 = [TBarLabels(:); DBSLabels(:); MicroLabels(:)];
            Degree.Level1 = [ones(1,app.FullEditField.Value)*2 ones(1,app.HalfEditField.Value) ones(1,app.DBSEditField.Value) ones(1,app.HeadstageEditField.Value)]';
            NodeLabels.Level2 = [];
            for i = 1:app.NumberOfAmps
                NodeLabels.Level2 = [NodeLabels.Level2 arrayfun(@(x) ['Amp',int2str(i),'-Bank',x],'A':'D','Uni',0)];
            end
            Degree.Level2 = [ones(1,app.FullEditField.Value*2) ones(1,app.HalfEditField.Value) ones(1,app.DBSEditField.Value) 2*ones(1,floor(app.HeadstageEditField.Value/2)) ones(1,mod(app.HeadstageEditField.Value,2))]';
            %             NodeLabels.Level2 = NodeLabels.Level2(1:length(Degree.Level2));
            
            Level1Node = {};
            for i = 1:length(NodeLabels.Level1)
                t = Degree.Level1(i);
                for j = 1:t
                    Level1Node = [Level1Node NodeLabels.Level1(i)];
                end
            end
            
            EdgeLabel = cell(app.NumberOfEdges,1);
            t = cumsum(Degree.Level1);
            EdgeLabel(t(Degree.Level1==2)-1) = {'[1:32]'};
            EdgeLabel(t(Degree.Level1==2)) = {'[33:64]'};
            t = cumsum(Degree.Level2);
            EdgeLabel(t(Degree.Level2==2)-1) = {'[1:16]'};
            EdgeLabel(t(Degree.Level2==2)) = {'[17:32]'};
            ind = find(cellfun(@isempty,EdgeLabel)==1);
            for i = 1:length(ind)
                if ind(i)==length(EdgeLabel)
                    EdgeLabel(ind(i)) = {'[1:16]'};
                else
                    EdgeLabel(ind(i)) = {'[1:32]'};
                end
            end
            
            metronome = 0;
            Level2Node = {};
            while ~isempty(Degree.Level2)
                if Degree.Level1(1)==2
                    switch app.ConnectionStrategyDropDown.Value
                        case 'Epilepsy (Hybrid)'
                            if metronome<=1
                                tmp = regexp(NodeLabels.Level2,'Amp\d','match');
                                tmp = vertcat(tmp{:});
                                [~,ind] = unique(tmp);
                                if length(ind)>=2
                                    Level2Node = [Level2Node NodeLabels.Level2(ind(1:2))];
                                    NodeLabels.Level2(ind(1:2)) = [];
                                    Degree.Level2(1:2) = [];
                                else
                                    Level2Node = [Level2Node NodeLabels.Level2(ind(1))];
                                    NodeLabels.Level2(ind(1)) = [];
                                    Degree.Level2(1) = [];
                                end
                                Degree.Level1(1) = [];
                            else
                                Level2Node = [Level2Node NodeLabels.Level2(1:2)];
                                NodeLabels.Level2(1:2) = [];
                                Degree.Level2(1:2) = [];
                                Degree.Level1(1) = [];
                            end
                            metronome = mod(metronome+1,4);
                        case 'Split'
                            tmp = regexp(NodeLabels.Level2,'Amp\d','match');
                            tmp = vertcat(tmp{:});
                            [~,ind] = unique(tmp);
                            if length(ind)>=2
                                Level2Node = [Level2Node NodeLabels.Level2(ind(1:2))];
                                NodeLabels.Level2(ind(1:2)) = [];
                                Degree.Level2(1:2) = [];
                            else
                                Level2Node = [Level2Node NodeLabels.Level2(ind(1))];
                                NodeLabels.Level2(ind(1)) = [];
                                Degree.Level2(1) = [];
                            end
                            Degree.Level1(1) = [];
                        case 'Sequential'
                            Level2Node = [Level2Node NodeLabels.Level2(1:2)];
                            NodeLabels.Level2(1:2) = [];
                            Degree.Level2(1:2) = [];
                            Degree.Level1(1) = [];
                    end
                elseif Degree.Level1(1)==Degree.Level2(1)
                    Level2Node = [Level2Node NodeLabels.Level2(1)];
                    NodeLabels.Level2(1) = [];
                    Degree.Level2(1) = [];
                    Degree.Level1(1) = [];
                elseif Degree.Level2(1)==2
                    t = Degree.Level2(1);
                    for j = 1:t
                        Level2Node = [Level2Node NodeLabels.Level2(1)];
                    end
                    NodeLabels.Level2(1) = [];
                    Degree.Level2(1) = [];
                    Degree.Level1(1:t) = [];
                else
                    break
                end
            end
            
            app.Graph = digraph(Level1Node,Level2Node);
            plot(app.UIAxes,app.Graph,'LineWidth',1,'ArrowSize',12,'ArrowPosition',1,'MarkerSize',app.MarkerSize,'NodeColor',[0 1 0],'NodeFontSize',12','EdgeFontSize',10,'EdgeAlpha',1,'EdgeFontAngle','normal');
            AmpIndex = ~cellfun(@isempty, regexp(app.UIAxes.Children.NodeLabel,'Amp'));
            YAdjust = 0.05*(~AmpIndex-AmpIndex);
            NodeLabelsNew = app.UIAxes.Children.NodeLabel';
            ind = ~cellfun(@isempty,regexp(NodeLabelsNew,'Amp'));
            tmp = cellfun(@(x) regexp(x,'(Amp\d)-(Bank[ABCD])','tokens'),NodeLabelsNew(ind),'Uni',0);
            tmp = vertcat(tmp{:});
            NodeLabelsNew(ind) = tmp;
            text(app.UIAxes,app.UIAxes.Children.XData,app.UIAxes.Children.YData+YAdjust,NodeLabelsNew,'HorizontalAlignment','Center')
            ind = isgraphics(app.UIAxes.Children,'GraphPlot');
            app.UIAxes.Children(ind).NodeLabel = [];
            
            app.UIAxes.Children(ind).EdgeLabel = EdgeLabel;
        end
        
        
        function SetupLayoutIndices(app)
            app.ReorderingMatrix = zeros(app.ElectrodesPerNSP,app.NumberOfNSPs);
            AllBanks = [];
            for i = 1:app.NumberOfAmps
                AllBanks = [AllBanks; arrayfun(@(x) ['Amp',int2str(i),'-Bank',x],'A':'D','Uni',0)'];
            end
            BankOrder = unique(app.Graph.Edges.EndNodes(:,2),'stable');
            BankOrder = [BankOrder; setdiff(AllBanks,BankOrder)];
            [~,SortedBankIndices] = sort(BankOrder);
            [~,BankOrderIndices] = sort(SortedBankIndices);
            BankOrderIndices = [BankOrderIndices; (length(BankOrderIndices)+1:app.BanksPerNSP*app.NumberOfNSPs)'];
            BankOrderIndices = reshape(BankOrderIndices,[app.BanksPerNSP,app.NumberOfNSPs]);
            BankOrderIndices = arrayfun(@(x) BankOrderIndices(:,x)-app.BanksPerNSP*(x-1),1:app.NumberOfNSPs,'Uni',0);
            for i = 1:app.NumberOfNSPs
                a = reshape(1:app.ElectrodesPerNSP,[app.ElectrodesPerBank,app.BanksPerNSP]);
                a = a(:,BankOrderIndices{i});
                app.ReorderingMatrix(:,i) = a(:);
            end
        end
        
        function CustomGraph(app)
            NumberOfBanks = 2*app.FullEditField.Value + app.HalfEditField.Value + app.DBSEditField.Value + ceil(app.HeadstageEditField.Value/2);
            app.NumberOfTBars = app.FullEditField.Value + app.HalfEditField.Value;
            app.NumberOfAmps = ceil(NumberOfBanks/4);
            TBarLabels = arrayfun(@(x) ['TBar-',x],char(65:65+app.NumberOfTBars-1)','Uni',0)';
            DBSLabels = arrayfun(@(x) ['DBS-',int2str(x)],1:app.DBSEditField.Value,'Uni',0);
            MicroLabels = arrayfun(@(x) ['Micro-',int2str(x)],1:app.HeadstageEditField.Value,'Uni',0);
            NodeLabels = [TBarLabels(:); DBSLabels(:); MicroLabels(:)];
            for i = 1:app.NumberOfAmps
                NodeLabels = [NodeLabels; arrayfun(@(x) ['Amp',int2str(i),'-Bank',x],'A':'D','Uni',0)'];
            end
            app.Graph = digraph;
            app.Graph = addnode(app.Graph,NodeLabels);
            plot(app.UIAxes,app.Graph,'LineWidth',1,'ArrowSize',12,'ArrowPosition',1,'MarkerSize',app.MarkerSize,'NodeColor',[0 1 0],'NodeFontSize',12','EdgeFontSize',10,'EdgeAlpha',1,'EdgeFontAngle','normal','ButtonDownFcn',@DrawEdge);
%             layout(app.UIAxes.Children,'layered')
            
            function DrawEdge(~,event)
                app.Test = event;
                ind = isgraphics(app.UIAxes.Children,'GraphPlot');
                [~,indX] = min(abs(app.UIAxes.Children(ind).XData-event.IntersectionPoint(1)));
                tmpX = app.UIAxes.Children(ind).XData(indX);
                logX = app.UIAxes.Children(ind).XData==tmpX;
                [~,indY] = min(abs(app.UIAxes.Children(ind).YData-event.IntersectionPoint(2)));
                tmpY = app.UIAxes.Children(ind).YData(indY);
                logY = app.UIAxes.Children(ind).YData==tmpY;
                [~,indN] = find(logX+logY==2);
                highlight(app.UIAxes.Children(ind),app.UIAxes.Children(ind).NodeLabel(indN))
                if sum(app.UIAxes.Children(ind).MarkerSize>app.MarkerSize)>=2
                    indM = find(app.UIAxes.Children(ind).MarkerSize>app.MarkerSize);
                    app.Graph = addedge(app.Graph,app.Graph.Nodes.Name{indM(1)},app.Graph.Nodes.Name{indM(2)});
                    plot(app.UIAxes,app.Graph,'LineWidth',1,'ArrowSize',12,'ArrowPosition',1,'MarkerSize',app.MarkerSize,'NodeColor',[0 1 0],'NodeFontSize',12','EdgeFontSize',10,'EdgeAlpha',1,'EdgeFontAngle','normal','ButtonDownFcn',@DrawEdge);
                    
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MontageSetupWizardUIFigure.Visible = 'off';
            movegui(app.MontageSetupWizardUIFigure,'center')
            app.MontageSetupWizardUIFigure.Visible = 'on';
            try
                app.SetupApp = mainapp;
            catch
            end
            app.UIAxes.Toolbar.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MontageSetupWizardUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GenerateLayoutButton
        function GenerateLayoutButtonPushed(app, event)
            CalculateProperties(app)
            GenerateGraph(app)
            SetupLayoutIndices(app)
            if ~isempty(app.SetupApp)
                app.ApplyButton.Enable = 'on';
            end
        end

        % Close request function: MontageSetupWizardUIFigure
        function MontageSetupWizardUIFigureCloseRequest(app, event)
            try
                app.SetupApp.ConfigureCablingButton.Enable = 'on';
            catch
            end
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            reorderElectrodeID(app.SetupApp,app.ReorderingMatrix)
            app.SetupApp.ConfigureCablingButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: CustomButton
        function CustomButtonPushed(app, event)
            CustomGraph(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MontageSetupWizardUIFigure = uifigure('Visible', 'off');
            app.MontageSetupWizardUIFigure.Name = 'Montage Setup Wizard';
            app.MontageSetupWizardUIFigure.CloseRequestFcn = createCallbackFcn(app, @MontageSetupWizardUIFigureCloseRequest, true);
            app.MontageSetupWizardUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MontageSetupWizardUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NKTBarsPanel = uipanel(app.LeftPanel);
            app.NKTBarsPanel.Title = 'NK T-Bars';
            app.FullEditFieldLabel = uilabel(app.NKTBarsPanel);
            app.FullEditFieldLabel.Text = 'Full';
            app.FullEditField = uieditfield(app.NKTBarsPanel, 'numeric');
            app.FullEditField.Limits = [1 8];
            app.FullEditField.RoundFractionalValues = 'on';
            app.FullEditField.Value = 1;
            app.HalfEditFieldLabel = uilabel(app.NKTBarsPanel);
            app.HalfEditFieldLabel.Text = 'Half';
            app.HalfEditField = uieditfield(app.NKTBarsPanel, 'numeric');
            app.HalfEditField.Limits = [0 16];
            app.HalfEditField.RoundFractionalValues = 'on';
            app.ConnectionStrategyDropDownLabel = uilabel(app.NKTBarsPanel);
            app.ConnectionStrategyDropDownLabel.Text = 'Connection Strategy';
            app.ConnectionStrategyDropDown = uidropdown(app.NKTBarsPanel);
            app.ConnectionStrategyDropDown.Items = {'Epilepsy (Hybrid)', 'Split', 'Sequential'};
            app.ConnectionStrategyDropDown.Value = 'Epilepsy (Hybrid)';
            app.MicrowireHeadstagesPanel = uipanel(app.LeftPanel);
            app.MicrowireHeadstagesPanel.Title = 'Microwire Headstages';
            app.HeadstageEditField = uieditfield(app.MicrowireHeadstagesPanel, 'numeric');
            app.HeadstageEditField.Limits = [0 32];
            app.HeadstageEditField.RoundFractionalValues = 'on';
            app.RecordedDBSPanel = uipanel(app.LeftPanel);
            app.RecordedDBSPanel.Title = 'Recorded DBS';
            app.DBSEditField = uieditfield(app.RecordedDBSPanel, 'numeric');
            app.DBSEditField.Limits = [0 32];
            app.DBSEditField.RoundFractionalValues = 'on';
            app.GenerateLayoutButton = uibutton(app.LeftPanel, 'push');
            app.GenerateLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLayoutButtonPushed, true);
            app.GenerateLayoutButton.Text = 'Generate Layout';
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.CustomButton = uibutton(app.LeftPanel, 'push');
            app.CustomButton.ButtonPushedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomButton.Text = 'Custom';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.MontageSetupWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MontageSetupWizard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MontageSetupWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MontageSetupWizardUIFigure)
        end
    end
end